classdef FileNameWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        matLabel           matlab.ui.control.Label
        CancelButton       matlab.ui.control.Button
        FilenameEditField  matlab.ui.control.EditField
        FilenameLabel      matlab.ui.control.Label
        SaveLoadButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Main app
        Action;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, action)
            app.UIFigure.Visible = 'off';
            app.Action = action;
            
            if ~verLessThan('matlab','9.9')
                app.UIFigure.WindowStyle = 'modal';
            end
            
            switch action
                case 'save'
                    app.UIFigure.Name = 'Save system';
                    app.SaveLoadButton.Text = 'Save';
                case 'load'
                    app.UIFigure.Name = 'Load system';
                    app.SaveLoadButton.Text = 'Load';
                    app.SaveLoadButton.Enable = false;
            end
            
            movegui(app.UIFigure,'center');
            app.UIFigure.Resize = 'off';
            
            app.CallingApp = mainapp;
            
            app.UIFigure.Visible = 'on';
            
        end

        % Button pushed function: SaveLoadButton
        function SaveLoadButtonPushed(app, event)
            filename = app.FilenameEditField.Value;
            
            action = app.Action;
            
            switch action
                case 'save'
                    SaveState(app.CallingApp,filename)
                case 'load'
                    LoadState(app.CallingApp,filename)
            end
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changing function: FilenameEditField
        function FilenameEditFieldValueChanging(app, event)
            
            
            action = app.Action;
            filename = append(event.Value,'.mat');
            
            switch action
                case 'save'
                
                    if exist(filename,'file')
                        app.SaveLoadButton.Text = 'Overwrite';
                    else
                        app.SaveLoadButton.Text = 'Save';
                    end
                    
                case 'load'
                    
                    if ~exist(filename,'file')
                        app.SaveLoadButton.Enable = false;
                    else
                        app.SaveLoadButton.Enable = true;
                    end
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveLoadButton = uibutton(app.UIFigure, 'push');
            app.SaveLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLoadButtonPushed, true);
            app.SaveLoadButton.Text = 'Save';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangingFcn = createCallbackFcn(app, @FilenameEditFieldValueChanging, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.matLabel = uilabel(app.UIFigure);
            app.matLabel.Text = '.mat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileNameWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end