classdef LensSystem3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        SaveasMenu                 matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        QuitMenu                   matlab.ui.container.Menu
        TemplatesMenu              matlab.ui.container.Menu
        BlankMenu                  matlab.ui.container.Menu
        TelescopeMenu              matlab.ui.container.Menu
        MicroscopeMenu             matlab.ui.container.Menu
        TelephotolensMenu          matlab.ui.container.Menu
        TerrestrialtelescopeMenu   matlab.ui.container.Menu
        OptionsMenu                matlab.ui.container.Menu
        OpenGLMenu                 matlab.ui.container.Menu
        SoftwareMenu               matlab.ui.container.Menu
        HardwareMenu               matlab.ui.container.Menu
        HardwareBasicMenu          matlab.ui.container.Menu
        TraceButton                matlab.ui.control.Button
        SettingsmmPanel            matlab.ui.container.Panel
        x1                         matlab.ui.control.NumericEditField
        x2                         matlab.ui.control.NumericEditField
        PlotspotdiagramsatxLabel   matlab.ui.control.Label
        SpotPos                    matlab.ui.control.NumericEditField
        SpotCheck                  matlab.ui.control.CheckBox
        DrawCheck                  matlab.ui.control.CheckBox
        Plot3Dsystemfromx1Label    matlab.ui.control.Label
        AperturediameterLabel      matlab.ui.control.Label
        Aperture                   matlab.ui.control.NumericEditField
        tox2Label                  matlab.ui.control.Label
        NewFigures                 matlab.ui.control.CheckBox
        Offset                     matlab.ui.control.NumericEditField
        PlusButton                 matlab.ui.control.Button
        MinusButton                matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        LensesTab                  matlab.ui.container.Tab
        LensTable                  matlab.ui.control.Table
        TextArea                   matlab.ui.control.TextArea
        LensButton                 matlab.ui.control.Button
        ClearlensesButton          matlab.ui.control.Button
        CardinalPointsTab          matlab.ui.container.Tab
        CPTable                    matlab.ui.control.Table
        PlotoptionsPanel           matlab.ui.container.Panel
        PlotPPsCheck               matlab.ui.control.CheckBox
        PlotFPsCheck               matlab.ui.control.CheckBox
        EffectivefocallengthEditFieldLabel  matlab.ui.control.Label
        EffectiveFocalLength       matlab.ui.control.NumericEditField
        ObjectTab                  matlab.ui.container.Tab
        ObjectTable                matlab.ui.control.Table
        ObjectButton               matlab.ui.control.Button
        RaysTab                    matlab.ui.container.Tab
        RayTable                   matlab.ui.control.Table
        LayerButton                matlab.ui.control.Button
        ColorSelectionButtonGroup  matlab.ui.container.ButtonGroup
        RedButton                  matlab.ui.control.RadioButton
        GreenButton                matlab.ui.control.RadioButton
        BlueButton                 matlab.ui.control.RadioButton
        RGBButton                  matlab.ui.control.RadioButton
        PositionsTab               matlab.ui.container.Tab
        PosTable                   matlab.ui.control.Table
        StorePosCheckBox           matlab.ui.control.CheckBox
        DirectionsTab              matlab.ui.container.Tab
        DirTable                   matlab.ui.control.Table
        StoreDirsCheckBox          matlab.ui.control.CheckBox
        AnglesTab                  matlab.ui.container.Tab
        AnglesTable                matlab.ui.control.Table
        StoreAnglesCheckBox        matlab.ui.control.CheckBox
        SystemDataPanel            matlab.ui.container.Panel
        GLabel                     matlab.ui.control.Label
        MagField                   matlab.ui.control.NumericEditField
        ExitangledevLabel          matlab.ui.control.Label
        AngleDeviationField        matlab.ui.control.NumericEditField
        degLabel                   matlab.ui.control.Label
        AngleDevGauge              matlab.ui.control.LinearGauge
        OptimizeCheckBox           matlab.ui.control.CheckBox
        TracetypeButtonGroup       matlab.ui.container.ButtonGroup
        NormalButton               matlab.ui.control.RadioButton
        ReverseButton              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        FileNameWindowApp;
        CurrentFileName = [];
        Template = 'blank';
        RayColor = 'red';
        Red = 656;
        Green = 530;
        Blue = 450;
        SpotInc = .1;
        Materials = {'F.Silica', ...
                    'N-BK7 (SCHOTT)', ...
                    'N-LASF9 (SCHOTT)', ...
                    'N-SF5 (SCHOTT)'};
        MinObjectX = -1e6;
        MaxRadius = 1e12;
        TraceType = 'Normal';
        SystemHandle = [];
        SpotHandle= [];
        
        ValuesToSave =  {'DrawCheck','x1','x2','SpotCheck','SpotPos', ...
                        'Offset','Aperture','PlotFPsCheck', ...
                        'PlotPPsCheck','EffectiveFocalLength', ...
                        'StorePosCheckBox','StoreDirsCheckBox', ...
                        'StoreAnglesCheckBox','MagField', ...
                        'AngleDeviationField','AngleDevGauge', ...
                        'NewFigures'};
                    
        DataToSave =    {'LensTable','CPTable','ObjectTable', ...
                        'RayTable','PosTable','DirTable','AnglesTable'};
                    
        TextToSave =    {'ColorSelectionButtonGroup', ...
                        'TracetypeButtonGroup'};
    end
    
    methods (Access = private)
        
        function G = TelescopeMag(~,S)
            % Computes telescope angular magnification
            
            AngleIn = acos(S(1,1,1,1,1));
            AngleOut = acos(S(1,size(S,2)-1,1,1,1));
            
            if AngleIn ~= 0
                G = AngleOut/AngleIn;
            else
                G = 0;
            end
        end
        
        function FillAngleTable(app,S)
            
            % Reformat S
            S = squeeze(S(:,1:size(S,2)-1,1,1,:));
            A = acos(S); % Angle values for central rays, first wavelength
            
            Acols = size(A,2)+1; % Number of table columns
            Arows = size(A,3); % Number of table rows
            
            ACnames = cell(Acols); % Column names cell array
            ACnames(1) = {'Ray'}; % First column name
            
            for i = 2:Acols % Column i
                ACnames(i) = {sprintf('Angle %i',i-1)};
            end
            
            app.AnglesTable.ColumnName = ACnames;
            
            AngleData = cell(Arows,Acols);
            
            for j = 1:Arows % Row j
                AngleData(j,1) = {sprintf('Central ray %i',j)};
                AngleData(j,2:Acols) = num2cell(A(1,:,j));
            end
            
            app.AnglesTable.Data = AngleData;
            app.AnglesTable.ColumnWidth = num2cell(80*ones(1,Acols));
        end
        
        function FillDirectionTable(app,S)
            
            % Reformat S
            S = squeeze(S(:,1:size(S,2)-1,1,1,:));
            
            DirTableCols = size(S,2)+2;
            DirTableRows = 3*size(S,3);
            
            % Create column names
            ColNames = cell(DirTableCols);
            ColNames(1:2) = {'Ray' 'Cosine'};
            
            for i = 3:DirTableCols % Column i
                ColNames(i) = {sprintf('Dir %i',i-2)};
            end
            
            app.DirTable.ColumnName = ColNames;
            
            DirData = cell(DirTableRows,DirTableCols);
            m = 1; % Ray index
            
            for j = 1:3:DirTableRows % Row j
                DirData(j:j+2,1) = {sprintf('Central ray %i',m); ''; ''};
                DirData(j:j+2,2) = {'sx'; 'sy'; 'sz'};
                DirData(j:j+2,3:DirTableCols) = num2cell(S(:,:,m));
                m = m+1;
            end
            
            app.DirTable.Data = DirData;
            app.DirTable.ColumnWidth = num2cell(80*ones(1,DirTableCols));
        end
        
        function FillPositionTable(app,R)
            
            % Reformat R
            R = squeeze(R(:,:,1,1,:));
            
            PosTableCols = size(R,2)+2;
            PosTableRows = 3*size(R,3);
            
            % Create column names
            ColNames = cell(PosTableCols);
            ColNames(1:2) = {'Ray' 'Coordinate'};
            
            for i = 3:PosTableCols % Column i
                ColNames(i) = {sprintf('Pos %i',i-2)};
            end
            
            app.PosTable.ColumnName = ColNames;
            
            % Create table data
            PosData = cell(PosTableRows,PosTableCols);
            m = 1; % Ray index
            
            for j = 1:3:PosTableRows % Row j
                PosData(j:j+2,1) = {sprintf('Central ray %i',m); ''; ''};
                PosData(j:j+2,2) = {'x'; 'y'; 'z'};
                PosData(j:j+2,3:PosTableCols) = num2cell(R(:,:,m));
                m = m+1;
            end
            
            % Store data
            app.PosTable.Data = PosData;
            app.PosTable.ColumnWidth = num2cell(80*ones(1,PosTableCols));
        end
        
        function G = MicroscopeMag(~,S,ObjectMatrix)
            % Compute microscope angular magnification from definition
            
            Object = ObjectMatrix(:,1);
            ObjectHeight = sqrt(Object(2)^2 + Object(3)^2);
            
            AngleIn = atan(ObjectHeight/250);
            AngleOut = acos(S(1,size(S,2)-1,1,1,1));
            
            if AngleIn ~= 0
                G = AngleOut/AngleIn;
            else
                G = 0;
            end
            
        end
        
        function dev = AngleDeviation(~,S)
            % Computes the maximal deviation of exiting rays from the 
            % average direction (1st wavelength and object)
            
            % Reformat S
            S = squeeze(S(:,size(S,2)-1,:,1,1));
            
            rays = size(S,2);
            
            % Mean direction of exiting rays
            sA = mean(S,2);
            
            AngleDevs = zeros(1,rays); % Allocation
            
            % Compute and store deviations from sA of exiting rays
            for i = 1:rays
                AngleDevs(i) = acosd( dot( sA,S(:,i) ) );
            end
            
            % Compute max deviation
            dev = max(abs(AngleDevs));
        end
        
        function lambda = getWavelength(app)
            % Aquire user specified wavelength(s)
            
            switch app.RayColor
                case 'red'
                    lambda = app.Red;
                case 'green'
                    lambda = app.Green;
                case 'blue'
                    lambda = app.Blue;
                otherwise
                    lambda = [app.Red app.Green app.Blue];
            end
        end

        function UpdateLensTable(app)
            
            % Get lens data
            LensData = app.LensTable.Data;
            
            if isempty(LensData)
                return;
            end
            
            lenses = size(LensData,1); % number of lenses
                
            % Get measurements
            LensMeasurements = cell2mat(LensData(:,2:6));
            
            % Get materials
            LensMaterials = LensData(:,8);
            
            % Get wavelength(s)
            lambda = getWavelength(app);
            
            % Set all x0 < 0 to 0
            negPos = LensMeasurements(:,5) < 0;
            LensMeasurements(negPos,5) = 0;
            
            % Set all x0 > 5000 to 5000
            bigPos = LensMeasurements(:,5) > 5e3;
            LensMeasurements(bigPos,5) = 5e3;
            
            % Set all D < 1 to 1
            smallD = LensMeasurements(:,3) < 1;
            LensMeasurements(smallD,3) = 1;
            
            % Set all D > 100 to 100
            bigD = LensMeasurements(:,3) > 100;
            LensMeasurements(bigD,3) = 100;
            
            % Set all T < 0 to 0
            smallT = LensMeasurements(:,4) < 0;
            LensMeasurements(smallT,4) = 0;
            
            % Set all T > 100 to 100
            bigT = LensMeasurements(:,4) > 100;
            LensMeasurements(bigT,4) = 100;
            
            n = zeros(lenses,1); % ref index array
            f = n; % focal length array
            
            for i = 1:lenses % Each lens
                
                % Compute and store ref index
                material = char(LensMaterials(i));
                n(i) = refIndex(lambda,material);
                
                Lens = LensMeasurements(i,:);
                
                if all(Lens(1:2)) % radii not zero
                    
                    % Check and update T if necessary
                    R1 = Lens(1);
                    R2 = Lens(2);
                    D = Lens(3);
                    T = Lens(4);
                    
                    if abs(R1) > app.MaxRadius
                        R1 = 1e12;
                    elseif abs(R1) < .5*D
                        R1 = .5*D*R1/abs(R1);
                        Lens(1) = R1;
                    end
                    
                    if abs(R2) > app.MaxRadius
                        R2 = 1e12;
                    elseif abs(R2) < .5*D
                        R2 = .5*D*R2/abs(R2);
                        Lens(2) = R2;
                    end
                    
                    % Compute minimum value of T for lens in question
                    
                    if R1 < 0 && R2 > 0     % double concave
                        Tmin = 0;
                    elseif R1 > 0 && R2 < 0 % double convex
                        Tmin = R1 - R2 ...
                            - sqrt(R1^2-(.5*D)^2) - sqrt(R2^2 - (.5*D)^2);
                    elseif R1 > 0 && R2 > 0 % meniscus 1
                        Tmin = R1 - R2 ...
                            - sqrt(R1^2-(.5*D)^2) + sqrt(R2^2 - (.5*D)^2);
                    else                    % mensicus 2
                        Tmin = R1 - R2 ...
                            + sqrt(R1^2-(.5*D)^2) - sqrt(R2^2 - (.5*D)^2);
                    end
                    
                    if T < Tmin % lens is too thin
                        % Set lens thickness to minimum value
                        Lens(4) = Tmin;
                    end
                    
                    % Compute and store focal length
                    f(i) = FocalLength([Lens n(i)]);
                    
                    % Store new lens measurements
                    LensMeasurements(i,:) = Lens;
                end
                
            end
            
            % Define new lens data
            LensData(:,2:7) = [num2cell(LensMeasurements) num2cell(n)];
            LensData(:,9) = num2cell(f);
            
            % Sort lenses by position
            LensData = sortrows(LensData,6);
            
            % Update lens table with new data
            app.LensTable.Data = LensData;
            
        end
        
        function UpdateObjectTable(app)
            
            % Get object data
            ObjectData = cell2mat(app.ObjectTable.Data(:,2:4));
            
            for w = 1:size(ObjectData,1) % object w
                
                Object = ObjectData(w,:);
            
                x = Object(1);
                y = Object(2);
                z = Object(3);
                
                xFrac = .1; % Maximum fraction of x allowed for y and z
                
                if isnan(x) || x > -1e-3 || imag(x) ~= 0
                    x = -1e-3;
                elseif x < app.MinObjectX % x is out of bounds
                    x = app.MinObjectX;
                end
                
                if isnan(y) || imag(y) ~= 0
                    y = 0;
                elseif abs(y) > xFrac*abs(x) % y is out of bounds
                    if y >= 0
                        y = xFrac*abs(x);
                    else
                        y = -xFrac*abs(x);
                    end
                end
                
                if isnan(z) || imag(z) ~= 0
                    z = 0;
                elseif abs(z) > xFrac*abs(x) % y is out of bounds
                    if z >= 0
                        z = xFrac*abs(x);
                    else
                        z = -xFrac*abs(x);
                    end
                end
            
                % Store new object data
                ObjectData(w,:) = [x y z];
            end
            
            % Update object table
            app.ObjectTable.Data(:,2:4) = num2cell(ObjectData);
            
            
        end
        
        function UpdateCardinalPtsTable(app)
            
            if isempty(app.LensTable.Data)
                return;
            end
            
            % Get lens status
            LensActive = cell2mat(app.LensTable.Data(:,10));
            
            % Get lens measurements
            LensMeasurements = cell2mat(app.LensTable.Data(:,2:6));
            LensMatrix = LensMeasurements(LensActive,:)';
            
            if all(LensActive == 0) || ...
                    any(LensMatrix(1:3,:) == 0,[1 2]) || ...
                    any(isnan(LensMatrix(1:4,:)),[1 2])
                app.CPTable.Data(:,2:3) = num2cell(zeros(3,2));
                app.EffectiveFocalLength.Value = 0;
                return;
            end
            
            % Get material data
            LensMaterials = app.LensTable.Data(:,8);
            MaterialArray = LensMaterials(LensActive);
            
            % Get wavelength(s)
            WaveVector = getWavelength(app);
            
            % Compute CardinalPts
            CP = CardinalPts(LensMatrix,MaterialArray,WaveVector);
            
            % Store CP's in table
            app.CPTable.Data(1:3,2:3) = num2cell(CP(1:3,:));
            
            % Store effective focal length
            app.EffectiveFocalLength.Value = abs(CP(4,1));
        end
        
        function FillSystemData(app,S,ObjectMatrix)
            
            switch app.Template
                case 'Telescope'
                    % Compute telescope data and add it to panel
                    app.MagField.Value = TelescopeMag(app,S);
                    
                    % Compute ang. dev
                    dev = AngleDeviation(app,S);
                    app.AngleDeviationField.Value = dev;
                    
                    % Define dev linear gauge
                    if dev > 45
                        GaugeLimit = 90;
                    elseif dev <= 45 && dev >= 10
                        GaugeLimit = 45;
                    elseif dev <= 10 && dev >= 1
                        GaugeLimit = 10;
                    elseif dev <= 1 && dev >= .1
                        GaugeLimit = 1;
                    else
                        GaugeLimit = .1;
                    end
                    
                    app.AngleDevGauge.Limits = [0 GaugeLimit];
                    app.AngleDevGauge.MajorTicks = ...
                        GaugeLimit*[0 .2 .4 .6 .8 1];
                    app.AngleDevGauge.Value = dev;
                    
                    % Enable telescope data panel
                    app.SystemDataPanel.Visible = 'on';
                    
                case 'Microscope'
                    % Compute microscope data and add it to panel
                    app.MagField.Value = MicroscopeMag(app,S,ObjectMatrix);
                    
                    % Compute ang. dev
                    dev = AngleDeviation(app,S);
                    app.AngleDeviationField.Value = dev;
                    
                    % Define dev linear gauge
                    if dev > 45
                        GaugeLimit = 90;
                    elseif dev <= 45 && dev >= 10
                        GaugeLimit = 45;
                    elseif dev <= 10 && dev >= 1
                        GaugeLimit = 10;
                    elseif dev <= 1 && dev >= .1
                        GaugeLimit = 1;
                    else
                        GaugeLimit = .1;
                    end
                    
                    app.AngleDevGauge.Limits = [0 GaugeLimit];
                    app.AngleDevGauge.MajorTicks = ...
                        GaugeLimit*[0 .2 .4 .6 .8 1];
                    app.AngleDevGauge.Value = dev;
                    
                    % Enable microscope data panel
                    app.SystemDataPanel.Visible = 'on';
                    
                case 'Telephoto'
                    
                case 'Terrestrial'
                    % Compute terrestrial data and add it to panel
                    app.MagField.Value = TelescopeMag(app,S);
                    
                    % Compute ang. dev
                    dev = AngleDeviation(app,S);
                    app.AngleDeviationField.Value = dev;
                    
                    % Define dev linear gauge
                    if dev > 45
                        GaugeLimit = 90;
                    elseif dev <= 45 && dev >= 10
                        GaugeLimit = 45;
                    elseif dev <= 10 && dev >= 1
                        GaugeLimit = 10;
                    elseif dev <= 1 && dev >= .1
                        GaugeLimit = 1;
                    else
                        GaugeLimit = .1;
                    end
                    
                    app.AngleDevGauge.Limits = [0 GaugeLimit];
                    app.AngleDevGauge.MajorTicks = ...
                        GaugeLimit*[0 .2 .4 .6 .8 1];
                    app.AngleDevGauge.Value = dev;
                    
                    % Enable terrestrial telescope data panel
                    app.SystemDataPanel.Visible = 'on';
                    
                case 'Blank'
            end
        end
        
        function DefaultSettings(app)
            % Reset filename
            app.CurrentFileName = '';
            
            % Make System data panel invisible
            app.OptimizeCheckBox.Value = false;
            app.OptimizeCheckBox.Enable = 'on';
            app.SystemDataPanel.Visible = 'off';
            
            app.Template = 'Blank';
            
            % Enter initial data 
            app.LensTable.Data = {};
            
            % Enable buttons
            app.LensButton.Enable = 'on';
            app.ClearlensesButton.Enable = 'on';
            
            app.CPTable.Data = {'PP' 0 0; 'FP' 0 0; 'NP' 0 0};
            app.PlotFPsCheck.Value = false;
            app.PlotPPsCheck.Value = false;
            
            app.RayTable.Data = ...
                {'Layer 1' 1 true; 'Layer 2' 5 true; 'Layer 3' 9 true};
            app.ColorSelectionButtonGroup.SelectedObject = app.RedButton;
            app.RayColor = 'red';
            
            app.ObjectTable.Data = {'Object' -1e6 1e4 1e4 true};
            
            app.PosTable.Data = {};
            app.PosTable.ColumnName = {};
            app.StorePosCheckBox.Value = false;
            
            app.DirTable.Data = {};
            app.DirTable.ColumnName = {};
            app.StoreDirsCheckBox.Value = false;
            
            app.AnglesTable.Data = {};
            app.AnglesTable.ColumnName = {};
            app.StoreAnglesCheckBox.Value = false;
            
            app.DrawCheck.Value = true;
            app.SpotCheck.Value = false;
            app.NewFigures.Value = false;
            app.Aperture.Value = 10;
            app.SpotPos.Value = 0;
            app.Offset.Value = 0.5;
            app.x1.Value = -100;
            app.x2.Value = 100;
            
            app.TracetypeButtonGroup.SelectedObject = app.NormalButton;
            app.TraceType = 'Normal';
            
        end
        
        function UpdateRayTable(app)
            
            % Get ray data
            RayVector = cell2mat(app.RayTable.Data(:,2));
            
            for i = 1:length(RayVector)
                
                val = RayVector(i);
                
                if ~isa(val,'double') || val <= 0 || isnan(val) || ...
                        imag(val) ~= 0
                    RayVector(i) = 1;
                elseif floor(val) ~= val
                    RayVector(i) = round(val);
                end
                
            end
            
            % Update ray table
            app.RayTable.Data(:,2) = num2cell(RayVector);
            
        end
        
        function UpdateSystemView(app)
            
            X1 = app.x1.Value;
            X2 = app.x2.Value;
            
            if isnan(X1) || imag(X1) ~= 0
                X1 = 0;
            end
            
            if isnan(X2) || imag(X2) ~= 0
                X2 = 0;
            end
            
            if X2 <= X1
                X1 = -50;
                X2 = 50;
                uialert(app.UIFigure,'Invalid plot range.', ...
                    'Input Error');
            end
            
            app.x1.Value = X1;
            app.x2.Value = X2;
            
        end
        
        function k = OptimizeSystem(app)
            
            % Get system data
            [LensMatrix,MaterialArray,ObjectMatrix,RayVector, ...
                WaveVector,ApertureD,SystemView,k,~] ...
                = GetSystemData(app);
            
            if k < 0
                return;
            end
            
            % Get lens status
            LensActive = cell2mat(app.LensTable.Data(:,10));
            
            % Get object status
            ObjectActive = cell2mat(app.ObjectTable.Data(:,5));
            
            if sum(ObjectActive) ~= 1
                uialert(app.UIFigure, ...
                    ['Optimization can only be performed for one (1)' ...
                    ' active object.'], ...
                    'Optimization Error');
                return;
            end
            
            % Define x precision
            dx = 1e-3;
            
            switch app.Template
                case 'Telescope'
                    
                    if sum(LensActive) ~= 2
                        uialert(app.UIFigure, ...
                            'Activate both lenses to optimize.', ...
                            'Optimization Error');
                        return;
                    end
                    
                    ObjectMatrix = [-1e6 1e4 1e4]';
                    
                    xInt = [0 1000];
                    LensMatrix(5,1) = 0;
                    
                    while abs(xInt(2)-xInt(1)) >= dx
                
                        % Set x to midpoint of interval
                        x = xInt(1) + .5*(xInt(2) - xInt(1));
                        
                        % Compute dev at midpoint
                        LensMatrix(5,2) = x;
                        
                        [~,S] = SystemTrace(LensMatrix,MaterialArray, ...
                            ObjectMatrix,RayVector,WaveVector, ...
                            ApertureD,SystemView,app.TraceType);
                        
                        dev = AngleDeviation(app,S);
                        
                        % Compute dev at midpoint + dx
                        LensMatrix(5,2) = x + dx;
                        
                        [~,S] = SystemTrace(LensMatrix,MaterialArray, ...
                            ObjectMatrix,RayVector,WaveVector, ...
                            ApertureD,SystemView,app.TraceType);
                        
                        newDev = AngleDeviation(app,S);
                        
                        % Define new interval
                        if newDev < dev
                            xInt(1) = x;
                        else
                            xInt(2) = x;
                        end
                
                    end
                    
                    % Update tables
                    app.LensTable.Data(1:2,6) = num2cell([0;x]);
                    app.ObjectTable.Data(ObjectActive,2:4) = ...
                        num2cell(ObjectMatrix');
                    
                    UpdateLensTable(app);
                    UpdateCardinalPtsTable(app);
                    UpdateObjectTable(app);
                    
                case 'Microscope'
                    
                    if sum(LensActive) ~= 2
                        uialert(app.UIFigure, ...
                            'Activate both lenses to optimize.', ...
                            'Optimization Error');
                        return;
                    end
                    
                    % Get object status
                    ObjectActive = cell2mat(app.ObjectTable.Data(:,5));
                    
                    CP = CardinalPts(LensMatrix,MaterialArray,WaveVector);
                    
                    xInt = [(CP(2,1)-5)  (CP(2,1)+5)];
                    ObjectMatrix(2:3,:) = .1;
                    
                    while abs(xInt(2)-xInt(1)) >= dx
                
                        % Set x to midpoint of interval
                        x = xInt(1) + .5*(xInt(2) - xInt(1));
                        
                        % Compute dev at midpoint
                        ObjectMatrix(1,:) = x;
                        
                        [~,S] = SystemTrace(LensMatrix,MaterialArray, ...
                            ObjectMatrix,RayVector,WaveVector, ...
                            ApertureD,SystemView,app.TraceType);
                        
                        dev = AngleDeviation(app,S);
                        
                        % Compute dev at midpoint + dx
                        ObjectMatrix(1,:) = x + dx;
                        
                        [~,S] = SystemTrace(LensMatrix,MaterialArray, ...
                            ObjectMatrix,RayVector,WaveVector, ...
                            ApertureD,SystemView,app.TraceType);
                        
                        newDev = AngleDeviation(app,S);
                        
                        % Define new interval
                        if newDev < dev
                            xInt(1) = x;
                        else
                            xInt(2) = x;
                        end
                
                    end
                    
                    app.ObjectTable.Data(ObjectActive,2:4) = ...
                        num2cell(ObjectMatrix');
                    
                    UpdateObjectTable(app);
                    UpdateCardinalPtsTable(app);
                    
                case 'Terrestrial'
                    
                    if sum(LensActive) ~= 3
                        uialert(app.UIFigure, ...
                            'Activate all three lenses to optimize.', ...
                            'Optimization Error');
                        return;
                    end
                    
                    xIncrement = .5;
                    
                    ObjectMatrix = [-1e6 1e4 1e4]';
                    
                    % Cardinal points for each lens
                    CPObjective = CardinalPts(LensMatrix(:,1), ...
                        MaterialArray(1),WaveVector);
                    CPRelay = CardinalPts(LensMatrix(:,2), ...
                        MaterialArray(2),WaveVector);
                    CPOcular = CardinalPts(LensMatrix(:,3), ...
                        MaterialArray(3),WaveVector);
                    
                    % Focal lengths
                    fObjective = CPObjective(4,2);
                    fRelay = CPRelay(4,2);
                    fOcular = CPOcular(4,2);
                    
                    % Adjust initial relay position if necessary
                    if LensMatrix(5,2) < fObjective + fRelay
                        LensMatrix(5,2) = fObjective + fRelay;
                    elseif LensMatrix(5,2) > ...
                            LensMatrix(5,3) - fOcular - fRelay
                        LensMatrix(5,2) = ...
                            LensMatrix(5,3) - fOcular - fRelay;
                    end
                    
                    % Compute dev where relay lens is
                    [~,S] = SystemTrace(LensMatrix,MaterialArray, ...
                        ObjectMatrix,RayVector,WaveVector, ...
                        ApertureD,SystemView,app.TraceType);
                    
                    OldDev = AngleDeviation(app,S);
                    
                    % Compute newDev at relay position + dx
                    LensMatrix(5,2) = LensMatrix(5,2) + dx;
                    
                    [~,S] = SystemTrace(LensMatrix,MaterialArray, ...
                        ObjectMatrix,RayVector,WaveVector, ...
                        ApertureD,SystemView,app.TraceType);
                    
                    NewDev = AngleDeviation(app,S);
                    
                    % If newDev is greater set increment to neg dir
                    if NewDev > OldDev
                        xIncrement = -xIncrement;
                    end
                    
                    % Reset relay position
                    LensMatrix(5,2) = LensMatrix(5,2) - dx;
                    
                    % Keep incrementing and determining NewDev until 
                    % NewDev > OldDev 
                    % (found approx. position for min deviation)
                    
                    NewDev = OldDev;
                    
                    while NewDev <= OldDev
                        
                        OldDev = NewDev;
                        
                        LensMatrix(5,2) = LensMatrix(5,2) + xIncrement;
                    
                        [~,S] = SystemTrace(LensMatrix, ...
                            MaterialArray,ObjectMatrix,RayVector, ...
                            WaveVector,ApertureD,SystemView, ...
                            app.TraceType);
                        
                        NewDev = AngleDeviation(app,S);
                        
                    end
                    
                    % Set interval around position
                    if xIncrement < 0
                        xInt = [LensMatrix(5,2) ...
                                (LensMatrix(5,2)-xIncrement)];
                    else
                        xInt = [(LensMatrix(5,2)-xIncrement) ...
                                LensMatrix(5,2)];
                    end
                    
                    % Optimize position within interval
                    while abs(xInt(2)-xInt(1)) > dx
                
                        % Set NewPos to midpoint of interval
                        NewPos = xInt(1) + .5*(xInt(2) - xInt(1));
                        
                        % Compute OldDev at midpoint
                        LensMatrix(5,2) = NewPos;
                        [~,S] = SystemTrace(LensMatrix, ...
                            MaterialArray,ObjectMatrix,RayVector, ...
                            WaveVector,ApertureD,SystemView, ...
                            app.TraceType);
                        
                        OldDev = AngleDeviation(app,S);
                        
                        % Compute NewDev at midpoint + dx
                        LensMatrix(5,2) = NewPos + dx;
                        [~,S] = SystemTrace(LensMatrix, ...
                            MaterialArray,ObjectMatrix,RayVector, ...
                            WaveVector,ApertureD,SystemView, ...
                            app.TraceType);
                        
                        NewDev = AngleDeviation(app,S);
                        
                        % Define next interval accordingly
                        if NewDev < OldDev
                            xInt(1) = NewPos;
                        else
                            xInt(2) = NewPos;
                        end
                        
                    end
                    
                    % Update displayed data
                    app.LensTable.Data(2,6) = num2cell(NewPos);
                    app.ObjectTable.Data(ObjectActive,2:4) = ...
                        num2cell(ObjectMatrix');
                    UpdateLensTable(app);
                    UpdateCardinalPtsTable(app);
                    UpdateObjectTable(app);
                    
                otherwise
                    k = 1;
                    return;
            end
        
        end
        
        function [LensMatrix,MaterialArray,ObjectMatrix,RayVector, ...
                    WaveVector,ApertureD,SystemView,k,ObjectNames] ... 
                    = GetSystemData(app)
            
            LensMatrix = []; MaterialArray = {}; ObjectMatrix = [];
            RayVector = []; WaveVector = []; ApertureD = 0;
            SystemView = []; ObjectNames = {};
            
            if isempty(app.LensTable.Data)
                uialert(app.UIFigure,'No lens in system.', ...
                    'System Trace Error');
                k = -1;
                return;
            end
            
            % Get lens status
            LensActive = cell2mat(app.LensTable.Data(:,10));
            
            if ~any(LensActive)
                uialert(app.UIFigure,'No active lens(es).', ...
                    'System Trace Error');
                k = -2;
                return;
            end
            
            % Get lens measurements
            LensMeasurements = cell2mat(app.LensTable.Data(:,2:6));
            LensMatrix = LensMeasurements(LensActive,:)';
            
            if ~all( LensMatrix(1:3,:), [1 2] ) || ...
                    ~all( LensMatrix(3,:) > 0, [1 2]) || ...
                    any( isnan(LensMatrix(1:5,:)), [1 2] ) || ...
                    any( imag(LensMatrix(1:5,:)), [1 2] )
                
                uialert(app.UIFigure,'Invalid lens measurement(s).', ...
                    'System Trace Error');
                k = -3;
                return;
            end
            
            % Set any too large radii to max radius value
            for j = 1:size(LensMatrix,2)
                
                if abs(LensMatrix(1,j)) > app.MaxRadius
                    LensMatrix(1,j) = app.MaxRadius;
                end
                
                if abs(LensMatrix(2,j)) > app.MaxRadius
                    LensMatrix(2,j) = app.MaxRadius;
                end 
                
            end
            
            % Get lens materials
            LensMaterials = app.LensTable.Data(:,8);
            MaterialArray = LensMaterials(LensActive);
            
            % Get object status
            ObjectActive = cell2mat(app.ObjectTable.Data(:,5));
            
            if ~any(ObjectActive)
                uialert(app.UIFigure,'No active object point(s).', ...
                    'System Trace Error');
                k = -4;
                return;
            elseif sum(ObjectActive) > 9
                uialert(app.UIFigure, ...
                    'No more than 9 object points allowed.', ...
                    'System Trace Error');
                k = -5;
                return;
            end
            
            % Get object data
            ObjectData = cell2mat(app.ObjectTable.Data(:,2:4));
            ObjectMatrix = ObjectData(ObjectActive,:)';
            ObjectNames = app.ObjectTable.Data(ObjectActive,:);
            
            % Get ray status
            LayerActive = cell2mat(app.RayTable.Data(:,3));
            
            if ~any(LayerActive)
                uialert(app.UIFigure,'No active ray layer(s).', ...
                    'System Trace Error');
                k = -5;
                return;
            end
            
            % Get ray data
            RayData = cell2mat(app.RayTable.Data(:,2));
            RayVector = RayData(LayerActive);
            
            % Get Wavelengths
            WaveVector = getWavelength(app);
            
            % Get aperture diameter
            ApertureD = app.Aperture.Value;
            
            % Get system data
            xmin = app.x1.Value;
            xmax = app.x2.Value;
            ymax = .5*max(LensMatrix(3,:));
            zmax = ymax;
            SystemView = [xmin xmax; -ymax ymax; -zmax zmax];
            
            k = 1;
        end
    end
    
    methods (Access = public)
        
        function SaveState(app,filename)
            
            state = struct();
            
            % save table data
            for i = 1:length(app.DataToSave)
                state.(app.DataToSave{i}).Data = ...
                    app.(app.DataToSave{i}).Data;
                state.(app.DataToSave{i}).ColumnName = ...
                    app.(app.DataToSave{i}).ColumnName;
            end
            
            % save values
            for j = 1:length(app.ValuesToSave)
                state.(app.ValuesToSave{j}).Value = ...
                    app.(app.ValuesToSave{j}).Value;
            end
            
            % save selections (text)
            for k = 1:length(app.TextToSave)
                state.(app.TextToSave{k}).SelectedObject.Text = ...
                    app.(app.TextToSave{k}).SelectedObject.Text;
            end
            
            % save other stuff
            state.Template = app.Template;
            state.RayColor = app.RayColor;
            state.SystemHandle = app.SystemHandle;
            state.SpotHandle = app.SpotHandle;
            
            state.AngleDevGauge.Limits = app.AngleDevGauge.Limits;
            state.AngleDevGauge.MajorTicks = app.AngleDevGauge.MajorTicks;
            
            save(append(filename,'.mat'),'state');
        end
        
        function LoadState(app,filename)
            
            app.CurrentFileName = filename;
            
            filename = append(filename,'.mat');
            
            if ~exist(filename,'file')
                uialert(app.UIFigure,append('Cannot find ',filename), ...
                    'Error loading state');
                return;
            end
            
            state = load(filename,'state');
            state = state.state;
            
            % load table data
            for i = 1:length(app.DataToSave)
                app.(app.DataToSave{i}).Data = ...
                    state.(app.DataToSave{i}).Data;
                app.(app.DataToSave{i}).ColumnName = ...
                    state.(app.DataToSave{i}).ColumnName;
            end
            
            % load values
            for i = 1:length(app.ValuesToSave)
                app.(app.ValuesToSave{i}).Value = ...
                    state.(app.ValuesToSave{i}).Value;
            end
            
            % load selections
            switch state.(app.TextToSave{1}).SelectedObject.Text
                case 'Red (656 nm)'
                    app.RayColor = 'red';
                    app.ColorSelectionButtonGroup.SelectedObject = ...
                        app.RedButton;
                case 'Green (530 nm)'
                    app.RayColor = 'green';
                    app.ColorSelectionButtonGroup.SelectedObject = ...
                        app.GreenButton;
                case 'Blue (450 nm)'
                    app.RayColor = 'blue';
                    app.ColorSelectionButtonGroup.SelectedObject = ...
                        app.BlueButton;
                case 'RGB'
                    app.RayColor = 'RGB';
                    app.ColorSelectionButtonGroup.SelectedObject = ...
                        app.RGBButton;
            end
            
            switch state.(app.TextToSave{2}).SelectedObject.Text
                case 'Normal'
                    app.TraceType = 'Normal';
                    app.TracetypeButtonGroup.SelectedObject = ...
                        app.NormalButton;
                    app.OptimizeCheckBox.Enable = 'on';
                case 'Reverse'
                    app.TraceType = 'Reverse';
                    app.TracetypeButtonGroup.SelectedObject = ...
                        app.ReverseButton;
                    app.OptimizeCheckBox.Value = false;
                    app.OptimizeCheckBox.Enable = 'off';
            end
            
            % load other stuff
            app.Template = state.Template;
            app.RayColor = state.RayColor;
            app.SystemHandle = state.SystemHandle;
            app.SpotHandle = state.SpotHandle;
            
            app.AngleDevGauge.Limits = state.AngleDevGauge.Limits;
            app.AngleDevGauge.MajorTicks = state.AngleDevGauge.MajorTicks;
            
            switch app.Template
                case 'Telescope'
                    app.SystemDataPanel.Visible = 'on';
                    app.SystemDataPanel.Title = 'Telescope data';
                    app.OptimizeCheckBox.Text = 'Optimize ocular position';
                    % Disable buttons
                    app.LensButton.Enable = 'off';
                    app.ClearlensesButton.Enable = 'off';
                case 'Microscope'
                    app.SystemDataPanel.Visible = 'on';
                    app.SystemDataPanel.Title = 'Microscope data';
                    app.OptimizeCheckBox.Text = ...
                        'Optimize object x-position';
                    % Disable buttons
                    app.LensButton.Enable = 'off';
                    app.ClearlensesButton.Enable = 'off';
                case 'Terrestrial'
                    app.SystemDataPanel.Visible = 'on';
                    app.SystemDataPanel.Title = ...
                        'Terrestrial telescope data';
                    app.OptimizeCheckBox.Text = ...
                        'Optimize relay lens position';
                    % Disable buttons
                    app.LensButton.Enable = 'off';
                    app.ClearlensesButton.Enable = 'off';
                case 'Telephoto'
                    app.SystemDataPanel.Visible = 'off';
                    app.OptimizeCheckBox.Value = 0;
                    % Disable buttons
                    app.LensButton.Enable = 'off';
                    app.ClearlensesButton.Enable = 'off';
                otherwise
                    app.SystemDataPanel.Visible = 'off';
                    app.SystemDataPanel.Title = 'SystemData';
                    % Enable buttons
                    app.LensButton.Enable = 'on';
                    app.ClearlensesButton.Enable = 'on';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            
            app.UIFigure.Name = 'Lens System 3D';
            DefaultSettings(app);
            
            pause(3);
            
            app.LensTable.ColumnFormat = ...
                {[] [] [] [] [] [] [] app.Materials []};
            
            opengl hardwarebasic;
            
            app.UIFigure.Resize = 'off';
            movegui(app.UIFigure,'center');
            
            app.UIFigure.Visible = 'on';
        end

        % Cell edit callback: LensTable
        function LensTableCellEdit(app, event)

              UpdateLensTable(app);
              UpdateCardinalPtsTable(app);
         
        end

        % Button pushed function: TraceButton
        function TraceButtonPushed(app, event)
            
            if app.OptimizeCheckBox.Value && strcmp(app.TraceType,'Normal')
                k = OptimizeSystem(app);
                
                if k < 0
                    return;
                end
            end
            
            % Get system data
            [LensMatrix,MaterialArray,ObjectMatrix,RayVector, ...
                WaveVector,ApertureD,SystemView,k,ObjectNames] ...
                = GetSystemData(app);
            
            if k < 0
                return;
            end
            
            % Perform SystemTrace
            [R,S] = SystemTrace(LensMatrix,MaterialArray,ObjectMatrix, ...
                RayVector,WaveVector,ApertureD,SystemView,app.TraceType);
            
            if app.DrawCheck.Value % draw 3D system plot
                
                if app.NewFigures.Value || isempty(app.SystemHandle) || ...
                        ~ishandle(app.SystemHandle)
                    % Create new figure
                    app.SystemHandle = figure;
                    % Plot in new figure
                    DisplaySystem(LensMatrix,ObjectMatrix,R,WaveVector, ...
                        SystemView,app.SystemHandle);
                    set(app.SystemHandle,'Position',[100 300 640 480]);
                else
                    % Clear current figure
                    clf(app.SystemHandle);
                    % Plot in current figure
                    DisplaySystem(LensMatrix,ObjectMatrix,R,WaveVector, ...
                        SystemView,app.SystemHandle);
                end
                
                if app.PlotPPsCheck.Value
                    CP = CardinalPts(LensMatrix,MaterialArray,WaveVector);
                    VerticalPlane(CP(1,1),'PP1',SystemView, ...
                        app.SystemHandle);
                    VerticalPlane(CP(1,2),'PP2',SystemView, ...
                        app.SystemHandle);
                end
                
                if app.PlotFPsCheck.Value
                    CP = CardinalPts(LensMatrix,MaterialArray,WaveVector);
                    VerticalPlane(CP(2,1),'FP1',SystemView, ...
                        app.SystemHandle);
                    VerticalPlane(CP(2,2),'FP2',SystemView, ...
                        app.SystemHandle);
                end
            else
                app.SystemHandle = [];
            end
            
            if app.SpotCheck.Value
                
                xp0 = app.SpotPos.Value;
                
                if app.NewFigures.Value || isempty(app.SpotHandle) || ...
                        ~ishandle(app.SpotHandle)
                    % Create new figure
                    app.SpotHandle = figure;
                    % Plot in new figure
                    SpotDiagram(xp0,R,S,WaveVector, ...
                        app.SpotHandle,app.SystemHandle,ObjectNames);
                    
                    if isgraphics(app.SpotHandle,'figure')
                        
                        height = ceil(size(R,5)/3)*280;
                        
                        if size(R,5) <= 3
                            width = size(R,5)*300;
                        else
                            width = 3*300;
                        end
                        set(app.SpotHandle,'Position',[350 50 width height]);
                    end
                else
                    % Clear current figure
                    clf(app.SpotHandle);
                    % Plot in current figure
                    SpotDiagram(xp0,R,S,WaveVector, ...
                        app.SpotHandle,app.SystemHandle,ObjectNames);
                    
                    if isgraphics(app.SpotHandle,'figure')
                        
                        height = ceil(size(R,5)/3)*280;
                        
                        if size(R,5) <= 3
                            width = size(R,5)*300;
                        else
                            width = 3*300;
                        end
                        set(app.SpotHandle,'Position', ...
                            get(app.SpotHandle,'Position').*[1 1 0 0] ...
                            + [0 0 width height]);
                        
                    end
                end
            end
            
            % Fill system data panel
            FillSystemData(app,S,ObjectMatrix);
            
            if app.StorePosCheckBox.Value
                % Fill position table
                FillPositionTable(app,R);
            end
            
            if app.StoreDirsCheckBox.Value
                % Fill direction table
                FillDirectionTable(app,S);
            end
            
            if app.StoreAnglesCheckBox.Value
                % Fill angle table
                FillAngleTable(app,S);
            end
            
        end

        % Button pushed function: LensButton
        function LensButtonPushed(app, event)
            oldData = app.LensTable.Data;
            LensName = sprintf('Lens %i',1 + size(oldData,1));
            material = char(app.Materials(1));
            lambda = getWavelength(app);
            n = refIndex(lambda,material);
            newRow = {LensName 0 0 25 0 0 n material 0 false};
            newData = [oldData; newRow];
            app.LensTable.Data = newData;
        end

        % Button pushed function: LayerButton
        function LayerButtonPushed(app, event)
            oldData = app.RayTable.Data;
            LayerName = sprintf('Layer %i',1 + size(oldData,1));
            newRow = {LayerName 0 false};
            newData = [oldData; newRow];
            app.RayTable.Data = newData;
        end

        % Callback function
        function ObjectButtonPushed(app, event)
%             oldData = app.ObjectTable.Data;
%             ObjectName = sprintf('Object %i',1 + size(oldData,1));
%             newRow = {ObjectName 0 0 0 false};
%             newData = [oldData; newRow];
%             app.ObjectTable.Data = newData;
        end

        % Cell edit callback: ObjectTable
        function ObjectTableCellEdit(app, event)
            UpdateObjectTable(app);
        end

        % Menu selected function: BlankMenu
        function BlankMenuSelected(app, event)
            % Clear settings
            DefaultSettings(app);
        end

        % Menu selected function: TelescopeMenu
        function TelescopeMenuSelected(app, event)
            % Reset filename
            app.CurrentFileName = '';
            
            % Activate telescope template
            app.Template = 'Telescope';
            
            % Reset system data panel
            app.MagField.Value = 0;
            app.AngleDeviationField.Value = 0;
            app.AngleDevGauge.Value = 0;
            app.OptimizeCheckBox.Text = 'Optimize ocular position';
            app.OptimizeCheckBox.Value = true;
            app.OptimizeCheckBox.Enable = 'on';
            app.SystemDataPanel.Visible = 'on';
            app.SystemDataPanel.Title = 'Telescope data';
            
            % Specify lens data
            material = 'F.Silica';
            lambda = getWavelength(app);
            n = refIndex(lambda,material);
            Lens1 = [0 0 25 0 0 n];
            Lens2 = [0 0 25 0 50 n];
            app.LensTable.Data = ... 
                ['Objective' num2cell(Lens1) material 0 false; ...
                'Ocular' num2cell(Lens2) material 0 false];
            
            % Disable buttons
            app.LensButton.Enable = 'off';
            app.ClearlensesButton.Enable = 'off';
            
            % Specify Object data
            app.ObjectTable.Data = {'Object point 1' -1e6 1e4 1e4 true};
            
            % Specify ray data
            app.RayTable.Data = ...
                {'Layer 1' 1 true; 'Layer 2' 5 true; 'Layer 3' 9 true};
            app.ColorSelectionButtonGroup.SelectedObject = app.RedButton;
            app.RayColor = 'red';
            
            % Clear other tables
            app.PosTable.Data = {};
            app.PosTable.ColumnName = {};
            app.StorePosCheckBox.Value = false;
            
            app.DirTable.Data = {};
            app.DirTable.ColumnName = {};
            app.StoreDirsCheckBox.Value = false;
            
            app.AnglesTable.Data = {};
            app.AnglesTable.ColumnName = {};
            app.StoreAnglesCheckBox.Value = false;
            
            % Specify Settings
            app.Aperture.Value = 20;
            app.DrawCheck.Value = true;
            app.x1.Value = -50;
            app.x2.Value = 300;
            app.SpotPos.Value = 0;
            app.SpotCheck.Value = false;
            app.Offset.Value = .5;
            app.NewFigures.Value = false;
            app.PlotFPsCheck.Value = false;
            app.PlotPPsCheck.Value = false;
            
            app.TracetypeButtonGroup.SelectedObject = app.NormalButton;
            app.TraceType = 'Normal';
            
            % Update cardinal pts table
            UpdateCardinalPtsTable(app);
        end

        % Display data changed function: LensTable
        function LensTableDisplayDataChanged(app, event)
            %newDisplayData = app.LensTable.DisplayData;
            
        end

        % Menu selected function: MicroscopeMenu
        function MicroscopeMenuSelected(app, event)
            % Reset filename
            app.CurrentFileName = '';
            
            % Activate microscope template
            app.Template = 'Microscope';
            
            % Reset system data panel
            app.MagField.Value = 0;
            app.AngleDeviationField.Value = 0;
            app.AngleDevGauge.Value = 0;
            app.OptimizeCheckBox.Text = 'Optimize object x-position';
            app.OptimizeCheckBox.Value = true;
            app.OptimizeCheckBox.Enable = 'on';
            app.SystemDataPanel.Visible = 'on';
            app.SystemDataPanel.Title = 'Microscope data';
            
            % Specify lens data
            material = 'F.Silica';
            lambda = getWavelength(app);
            n = refIndex(lambda,material);
            Lens1 = [0 0 25 0 0 n];
            Lens2 = [0 0 25 0 50 n];
            app.LensTable.Data = ... 
                ['Objective' num2cell(Lens1) material 0 false; ...
                'Ocular' num2cell(Lens2) material 0 false];
            
            % Disable buttons
            app.LensButton.Enable = 'off';
            app.ClearlensesButton.Enable = 'off';
            
            % Specify Object data
            app.ObjectTable.Data = {'Object point 1' -20 0 .1 true};
            
            % Specify ray data
            app.RayTable.Data = ...
                {'Layer 1' 1 true; 'Layer 2' 5 true; 'Layer 3' 9 true};
            app.ColorSelectionButtonGroup.SelectedObject = app.RedButton;
            app.RayColor = 'red';
            
            % Clear other tables
            app.PosTable.Data = {};
            app.PosTable.ColumnName = {};
            app.StorePosCheckBox.Value = false;
            
            app.DirTable.Data = {};
            app.DirTable.ColumnName = {};
            app.StoreDirsCheckBox.Value = false;
            
            app.AnglesTable.Data = {};
            app.AnglesTable.ColumnName = {};
            app.StoreAnglesCheckBox.Value = false;
            
            % Specify settings
            app.Aperture.Value = 20;
            app.DrawCheck.Value = true;
            app.x1.Value = -20;
            app.x2.Value = 320;
            app.SpotPos.Value = 250;
            app.Offset.Value = .5;
            app.SpotCheck.Value = false;
            app.NewFigures.Value = false;
            app.PlotFPsCheck.Value = true;
            app.PlotPPsCheck.Value = false;
            
            app.TracetypeButtonGroup.SelectedObject = app.NormalButton;
            app.TraceType = 'Normal';
            
            % Update cardinal pts table
            UpdateCardinalPtsTable(app);
        end

        % Selection changed function: ColorSelectionButtonGroup
        function ColorSelectionButtonGroupSelectionChanged(app, event)
            Button = app.ColorSelectionButtonGroup.SelectedObject;
            
            % Update RayColor prop
            switch Button
                case app.RedButton
                    app.RayColor = 'red';
                case app.GreenButton
                    app.RayColor = 'green';
                case app.BlueButton
                    app.RayColor = 'blue';
                otherwise
                    app.RayColor = 'RGB';
            end
            
            % Update tables
            UpdateLensTable(app);
            UpdateCardinalPtsTable(app);
            
        end

        % Selection changed function: TracetypeButtonGroup
        function TracetypeButtonGroupSelectionChanged(app, event)
            Button = app.TracetypeButtonGroup.SelectedObject;
            
            switch Button
                case app.NormalButton
                    app.TraceType = 'Normal';
                    app.OptimizeCheckBox.Enable = 'on';
                otherwise
                    app.TraceType = 'Reverse';
                    app.OptimizeCheckBox.Value = false;
                    app.OptimizeCheckBox.Enable = 'off';
            end
            
        end

        % Menu selected function: TelephotolensMenu
        function TelephotolensMenuSelected(app, event)
            % Reset filename
            app.CurrentFileName = '';
            
            % Activate telephoto template
            app.Template = 'Telephoto';
            app.OptimizeCheckBox.Value = false;
            app.OptimizeCheckBox.Enable = 'off';
            app.SystemDataPanel.Visible = 'off';
            app.SystemDataPanel.Title = 'Telephoto lens data';
            
            % Specify lens data
            material = 'F.Silica';
            lambda = getWavelength(app);
            n = refIndex(lambda,material);
            Lens1 = [0 0 25 0 0 n];
            Lens2 = [0 0 25 0 50 n];
            app.LensTable.Data = ... 
                ['Front lens' num2cell(Lens1) material 0 false; ...
                'Neg. lens' num2cell(Lens2) material 0 false];
            
            % Disable buttons
            app.LensButton.Enable = 'off';
            app.ClearlensesButton.Enable = 'off';
            
            % Specify Object data
            app.ObjectTable.Data = {'Object point 1' -1e6 1e4 1e4 true};
            
            % Specify Ray data
            app.RayTable.Data = ...
                {'Layer 1' 1 true; 'Layer 2' 5 true; 'Layer 3' 9 true};
            app.ColorSelectionButtonGroup.SelectedObject = app.RedButton;
            app.RayColor = 'red';
            
            % Clear other tables
            app.PosTable.Data = {};
            app.PosTable.ColumnName = {};
            app.StorePosCheckBox.Value = false;
            
            app.DirTable.Data = {};
            app.DirTable.ColumnName = {};
            app.StoreDirsCheckBox.Value = false;
            
            app.AnglesTable.Data = {};
            app.AnglesTable.ColumnName = {};
            app.StoreAnglesCheckBox.Value = false;
            
            % Specify Settings
            app.Aperture.Value = 20;
            app.DrawCheck.Value = true;
            app.x1.Value = -80;
            app.x2.Value = 150;
            app.SpotCheck.Value = true;
            app.SpotPos.Value = 123.15;
            app.Offset.Value = .5;
            app.NewFigures.Value = false;
            app.PlotFPsCheck.Value = true;
            app.PlotPPsCheck.Value = false;
            
            app.TracetypeButtonGroup.SelectedObject = app.NormalButton;
            app.TraceType = 'Normal';
            
            % Update cardinal pts table
            UpdateCardinalPtsTable(app);
        end

        % Menu selected function: TerrestrialtelescopeMenu
        function TerrestrialtelescopeMenuSelected(app, event)
            % Reset filename
            app.CurrentFileName = '';
            
            % Activate terrestrial template
            app.Template = 'Terrestrial';
            
            % Reset system data panel
            app.MagField.Value = 0;
            app.AngleDeviationField.Value = 0;
            app.AngleDevGauge.Value = 0;
            app.OptimizeCheckBox.Text = 'Optimize relay lens position';
            app.OptimizeCheckBox.Value = true;
            app.OptimizeCheckBox.Enable = 'on';
            app.SystemDataPanel.Visible = 'on';
            app.SystemDataPanel.Title = 'Terrestrial telescope data';
            
            % Specify lens data
            material = 'F.Silica';
            lambda = getWavelength(app);
            n = refIndex(lambda,material);
            Lens1 = [0 0 25 0 0 n];
            Lens2 = [0 0 25 0 50 n];
            Lens3 = [0 0 25 0 100 n];
            app.LensTable.Data = ... 
                ['Objective' num2cell(Lens1) material 0 false; ...
                'Relay lens' num2cell(Lens2) material 0 false; ...
                'Ocular' num2cell(Lens3) material 0 false];
            
            % Disable buttons
            app.LensButton.Enable = 'off';
            app.ClearlensesButton.Enable = 'off';
            
            % Specify Object data
            app.ObjectTable.Data = {'Object point 1' -1e6 1e4 1e4 true};
            
            % Specify ray data
            app.RayTable.Data = ...
                {'Layer 1' 1 true; 'Layer 2' 5 true; 'Layer 3' 9 true};
            app.ColorSelectionButtonGroup.SelectedObject = app.RedButton;
            app.RayColor = 'red';
            
            % Clear other tables
            app.PosTable.Data = {};
            app.PosTable.ColumnName = {};
            app.StorePosCheckBox.Value = false;
            
            app.DirTable.Data = {};
            app.DirTable.ColumnName = {};
            app.StoreDirsCheckBox.Value = false;
            
            app.AnglesTable.Data = {};
            app.AnglesTable.ColumnName = {};
            app.StoreAnglesCheckBox.Value = false;
            
            % Specify Settings
            app.Aperture.Value = 20;
            app.DrawCheck.Value = true;
            app.x1.Value = -50;
            app.x2.Value = 400;
            app.SpotPos.Value = 0;
            app.SpotCheck.Value = false;
            app.Offset.Value = .2;
            app.NewFigures.Value = false;
            app.PlotFPsCheck.Value = false;
            app.PlotPPsCheck.Value = false;
            
            app.TracetypeButtonGroup.SelectedObject = app.NormalButton;
            app.TraceType = 'Normal';
            
            % Update cardinal pts table
            UpdateCardinalPtsTable(app);
        end

        % Menu selected function: HardwareBasicMenu
        function HardwareBasicMenuSelected(app, event)
            opengl hardwarebasic;
            app.HardwareBasicMenu.Checked = 'on';
            app.SoftwareMenu.Checked = 'off';
            app.HardwareMenu.Checked = 'off';
        end

        % Menu selected function: HardwareMenu
        function HardwareMenuSelected(app, event)
            opengl hardware;
            app.HardwareMenu.Checked = 'on';
            app.SoftwareMenu.Checked = 'off';
            app.HardwareBasicMenu.Checked = 'off';
        end

        % Menu selected function: SoftwareMenu
        function SoftwareMenuSelected(app, event)
            opengl software;
            app.SoftwareMenu.Checked = 'on';
            app.HardwareMenu.Checked = 'off';
            app.HardwareBasicMenu.Checked = 'off';
        end

        % Button pushed function: ObjectButton
        function ObjectButtonPushed2(app, event)
            oldData = app.ObjectTable.Data;
            ObjectName = sprintf('Object point %i',1 + size(oldData,1));
            newRow = {ObjectName 0 0 0 false};
            newData = [oldData; newRow];
            app.ObjectTable.Data = newData;
        end

        % Cell edit callback: RayTable
        function RayTableCellEdit(app, event)
            UpdateRayTable(app);
        end

        % Value changed function: x1
        function x1ValueChanged(app, event)
            UpdateSystemView(app);
        end

        % Value changed function: x2
        function x2ValueChanged(app, event)
            UpdateSystemView(app);
        end

        % Value changed function: Aperture
        function ApertureValueChanged(app, event)
            value = app.Aperture.Value;
            
            if isnan(value) || value < 0 || value == Inf || ...
                    imag(value) ~= 0
                value = 0;
            end
            
            app.Aperture.Value = value;
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % Call FileNameWindow to get filename
            app.FileNameWindowApp = FileNameWindow(app,'save');
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            
            % Disable all other fields
            
            % Call FileNameWindow to get filename
            app.FileNameWindowApp = FileNameWindow(app,'load');
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            filename = append(app.CurrentFileName,'.mat');
            
            if ~exist(filename,'file')
                % Call FileNameWindow to get filename
                app.FileNameWindowApp = FileNameWindow(app,'save');
            else
                % Write to current file
                SaveState(app,app.CurrentFileName);
            end
            
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app);
        end

        % Button pushed function: ClearlensesButton
        function ClearlensesButtonPushed(app, event)
            app.LensTable.Data = {};
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            app.SpotPos.Value = app.SpotPos.Value - app.SpotInc;
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            app.SpotPos.Value = app.SpotPos.Value + app.SpotInc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.TemplatesMenu = uimenu(app.UIFigure);
            app.TemplatesMenu.Text = 'Templates';
            app.BlankMenu = uimenu(app.TemplatesMenu);
            app.BlankMenu.MenuSelectedFcn = createCallbackFcn(app, @BlankMenuSelected, true);
            app.BlankMenu.Text = 'Blank';
            app.TelescopeMenu = uimenu(app.TemplatesMenu);
            app.TelescopeMenu.MenuSelectedFcn = createCallbackFcn(app, @TelescopeMenuSelected, true);
            app.TelescopeMenu.Text = 'Telescope';
            app.MicroscopeMenu = uimenu(app.TemplatesMenu);
            app.MicroscopeMenu.MenuSelectedFcn = createCallbackFcn(app, @MicroscopeMenuSelected, true);
            app.MicroscopeMenu.Text = 'Microscope';
            app.TelephotolensMenu = uimenu(app.TemplatesMenu);
            app.TelephotolensMenu.MenuSelectedFcn = createCallbackFcn(app, @TelephotolensMenuSelected, true);
            app.TelephotolensMenu.Text = 'Telephoto lens';
            app.TerrestrialtelescopeMenu = uimenu(app.TemplatesMenu);
            app.TerrestrialtelescopeMenu.MenuSelectedFcn = createCallbackFcn(app, @TerrestrialtelescopeMenuSelected, true);
            app.TerrestrialtelescopeMenu.Text = 'Terrestrial telescope';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.OpenGLMenu = uimenu(app.OptionsMenu);
            app.OpenGLMenu.Text = 'OpenGL';
            app.SoftwareMenu = uimenu(app.OpenGLMenu);
            app.SoftwareMenu.MenuSelectedFcn = createCallbackFcn(app, @SoftwareMenuSelected, true);
            app.SoftwareMenu.Text = 'Software';
            app.HardwareMenu = uimenu(app.OpenGLMenu);
            app.HardwareMenu.MenuSelectedFcn = createCallbackFcn(app, @HardwareMenuSelected, true);
            app.HardwareMenu.Text = 'Hardware';
            app.HardwareBasicMenu = uimenu(app.OpenGLMenu);
            app.HardwareBasicMenu.MenuSelectedFcn = createCallbackFcn(app, @HardwareBasicMenuSelected, true);
            app.HardwareBasicMenu.Checked = 'on';
            app.HardwareBasicMenu.Text = 'Hardware Basic';
            app.TraceButton = uibutton(app.UIFigure, 'push');
            app.TraceButton.ButtonPushedFcn = createCallbackFcn(app, @TraceButtonPushed, true);
            app.TraceButton.Text = 'System Trace';
            app.SettingsmmPanel = uipanel(app.UIFigure);
            app.SettingsmmPanel.Title = 'Settings [mm]';
            app.x1 = uieditfield(app.SettingsmmPanel, 'numeric');
            app.x1.ValueDisplayFormat = '%.0f';
            app.x1.ValueChangedFcn = createCallbackFcn(app, @x1ValueChanged, true);
            app.x1.Tooltip = {'Draw lens system in 3D over interval specified by x1 and x2.'};
            app.x1.Value = -100;
            app.x2 = uieditfield(app.SettingsmmPanel, 'numeric');
            app.x2.ValueDisplayFormat = '%.0f';
            app.x2.ValueChangedFcn = createCallbackFcn(app, @x2ValueChanged, true);
            app.x2.Tooltip = {'Draw lens system in 3D over interval specified by x1 and x2.'};
            app.x2.Value = 100;
            app.PlotspotdiagramsatxLabel = uilabel(app.SettingsmmPanel);
            app.PlotspotdiagramsatxLabel.Tooltip = {'Plot a central spot diagram at the given position, with specified offset for adjacent diagrams.'};
            app.PlotspotdiagramsatxLabel.Text = 'Plot spot diagram(s) at x =';
            app.SpotPos = uieditfield(app.SettingsmmPanel, 'numeric');
            app.SpotPos.ValueDisplayFormat = '%.3f';
            app.SpotPos.Tooltip = {'Plot a central spot diagram at the given position, with specified offset for adjacent diagrams.'};
            app.SpotCheck = uicheckbox(app.SettingsmmPanel);
            app.SpotCheck.Tooltip = {'Plot a central spot diagram at the given position, with specified offset for adjacent diagrams.'};
            app.SpotCheck.Text = '';
            app.SpotCheck.Value = true;
            app.DrawCheck = uicheckbox(app.SettingsmmPanel);
            app.DrawCheck.Tooltip = {'Draw lens system in 3D over interval specified by x1 and x2.'};
            app.DrawCheck.Text = '';
            app.DrawCheck.Value = true;
            app.Plot3Dsystemfromx1Label = uilabel(app.SettingsmmPanel);
            app.Plot3Dsystemfromx1Label.Tooltip = {'Draw lens system in 3D over interval specified by x1 and x2.'};
            app.Plot3Dsystemfromx1Label.Text = 'Plot 3D system from x1 =';
            app.AperturediameterLabel = uilabel(app.SettingsmmPanel);
            app.AperturediameterLabel.Text = {'Aperture diameter:'; ''};
            app.Aperture = uieditfield(app.SettingsmmPanel, 'numeric');
            app.Aperture.ValueDisplayFormat = '%.3f';
            app.Aperture.ValueChangedFcn = createCallbackFcn(app, @ApertureValueChanged, true);
            app.Aperture.Tooltip = {'Diameter in mm of circular aperture placed at first lens vertex.'};
            app.tox2Label = uilabel(app.SettingsmmPanel);
            app.tox2Label.Tooltip = {'Draw lens system in 3D over interval specified by x1 and x2.'};
            app.tox2Label.Text = 'to x2 =';
            app.NewFigures = uicheckbox(app.SettingsmmPanel);
            app.NewFigures.Tooltip = {'Plots 3D system and spot diagrams in new figure(s) when checked. Otherwise plots in last created figure(s), if they exist.'};
            app.NewFigures.Text = ' Plot in new figure(s)';
            app.Offset = uieditfield(app.SettingsmmPanel, 'numeric');
            app.Offset.Editable = 'off';
            app.Offset.Enable = 'off';
            app.Offset.Visible = 'off';
            app.PlusButton = uibutton(app.SettingsmmPanel, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.SettingsmmPanel, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LensesTab = uitab(app.TabGroup);
            app.LensesTab.Title = 'Lenses';
            app.LensTable = uitable(app.LensesTab);
            app.LensTable.ColumnName = {'Lens'; 'R1 [mm]'; 'R2 [mm]'; 'D [mm]'; 'T [mm]'; 'x0 [mm]'; 'n'; 'Material'; 'f [mm]'; 'Active'};
            app.LensTable.RowName = {};
            app.LensTable.ColumnEditable = [true true true true true true false true false true];
            app.LensTable.CellEditCallback = createCallbackFcn(app, @LensTableCellEdit, true);
            app.LensTable.DisplayDataChangedFcn = createCallbackFcn(app, @LensTableDisplayDataChanged, true);
            app.LensTable.Tooltip = {''};
            app.TextArea = uitextarea(app.LensesTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'R: Radius of curvature'; 'D: Diameter'; 'T: Thickness'; 'x0: Position'; 'n: Ref. index'; 'f: Focal length'};
            app.LensButton = uibutton(app.LensesTab, 'push');
            app.LensButton.ButtonPushedFcn = createCallbackFcn(app, @LensButtonPushed, true);
            app.LensButton.Text = 'Add lens';
            app.ClearlensesButton = uibutton(app.LensesTab, 'push');
            app.ClearlensesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlensesButtonPushed, true);
            app.ClearlensesButton.Text = 'Clear lenses';
            app.CardinalPointsTab = uitab(app.TabGroup);
            app.CardinalPointsTab.Title = 'Cardinal Points';
            app.CPTable = uitable(app.CardinalPointsTab);
            app.CPTable.ColumnName = {''; '1 [mm]'; '2 [mm]'};
            app.CPTable.RowName = {};
            app.CPTable.Tooltip = {'Absolute positions of principal points, focal points and nodal points of the system, in mm.'};
            app.PlotoptionsPanel = uipanel(app.CardinalPointsTab);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.PlotPPsCheck = uicheckbox(app.PlotoptionsPanel);
            app.PlotPPsCheck.Tooltip = {'Include principal planes when drawing 3D system.'};
            app.PlotPPsCheck.Text = 'Plot principal planes';
            app.PlotFPsCheck = uicheckbox(app.PlotoptionsPanel);
            app.PlotFPsCheck.Tooltip = {'Include focus planes when drawing 3D system.'};
            app.PlotFPsCheck.Text = 'Plot focal planes';
            app.EffectivefocallengthEditFieldLabel = uilabel(app.CardinalPointsTab);
            app.EffectivefocallengthEditFieldLabel.Text = 'Effective focal length:';
            app.EffectiveFocalLength = uieditfield(app.CardinalPointsTab, 'numeric');
            app.EffectiveFocalLength.ValueDisplayFormat = '%.3f';
            app.EffectiveFocalLength.Editable = 'off';
            app.EffectiveFocalLength.Tooltip = {'Effective front/back focal length of the system in mm, measured from the principal planes.'};
            app.ObjectTab = uitab(app.TabGroup);
            app.ObjectTab.Title = 'Object';
            app.ObjectTable = uitable(app.ObjectTab);
            app.ObjectTable.ColumnName = {'Object'; 'x [mm]'; 'y [mm]'; 'z [mm]'; 'Active'};
            app.ObjectTable.RowName = {};
            app.ObjectTable.ColumnEditable = [true true true true true];
            app.ObjectTable.CellEditCallback = createCallbackFcn(app, @ObjectTableCellEdit, true);
            app.ObjectButton = uibutton(app.ObjectTab, 'push');
            app.ObjectButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectButtonPushed2, true);
            app.ObjectButton.Text = 'Add object point';
            app.RaysTab = uitab(app.TabGroup);
            app.RaysTab.Title = 'Rays';
            app.RayTable = uitable(app.RaysTab);
            app.RayTable.ColumnName = {'Layer'; 'Rays'; 'Active'};
            app.RayTable.RowName = {};
            app.RayTable.ColumnEditable = true;
            app.RayTable.CellEditCallback = createCallbackFcn(app, @RayTableCellEdit, true);
            app.LayerButton = uibutton(app.RaysTab, 'push');
            app.LayerButton.ButtonPushedFcn = createCallbackFcn(app, @LayerButtonPushed, true);
            app.LayerButton.Text = 'Add layer';
            app.ColorSelectionButtonGroup = uibuttongroup(app.RaysTab);
            app.ColorSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorSelectionButtonGroupSelectionChanged, true);
            app.ColorSelectionButtonGroup.Title = 'Color Selection';
            app.RedButton = uiradiobutton(app.ColorSelectionButtonGroup);
            app.RedButton.Text = 'Red (656 nm)';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.ColorSelectionButtonGroup);
            app.GreenButton.Text = 'Green (530 nm)';
            app.BlueButton = uiradiobutton(app.ColorSelectionButtonGroup);
            app.BlueButton.Text = 'Blue (450 nm)';
            app.RGBButton = uiradiobutton(app.ColorSelectionButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.PositionsTab = uitab(app.TabGroup);
            app.PositionsTab.Title = 'Positions';
            app.PosTable = uitable(app.PositionsTab);
            app.PosTable.ColumnName = {''};
            app.PosTable.RowName = {};
            app.StorePosCheckBox = uicheckbox(app.PositionsTab);
            app.StorePosCheckBox.Tooltip = {'Store positions for the central rays from all object points. Uses the first wavelength.'};
            app.StorePosCheckBox.Text = 'Store positions';
            app.DirectionsTab = uitab(app.TabGroup);
            app.DirectionsTab.Title = 'Directions';
            app.DirTable = uitable(app.DirectionsTab);
            app.DirTable.ColumnName = {''};
            app.DirTable.RowName = {};
            app.StoreDirsCheckBox = uicheckbox(app.DirectionsTab);
            app.StoreDirsCheckBox.Tooltip = {'Store directional cosines for central rays from all object points. Uses the first wavelength.'};
            app.StoreDirsCheckBox.Text = 'Store directions';
            app.AnglesTab = uitab(app.TabGroup);
            app.AnglesTab.Title = 'Angles';
            app.AnglesTable = uitable(app.AnglesTab);
            app.AnglesTable.ColumnName = {''};
            app.AnglesTable.RowName = {};
            app.StoreAnglesCheckBox = uicheckbox(app.AnglesTab);
            app.StoreAnglesCheckBox.Tooltip = {'Store the angles between the central rays (from all objects) and the optical axis at all points. Uses the first wavelength.'};
            app.StoreAnglesCheckBox.Text = 'Store angles';
            app.SystemDataPanel = uipanel(app.UIFigure);
            app.SystemDataPanel.Title = 'SystemData';
            app.GLabel = uilabel(app.SystemDataPanel);
            app.GLabel.Text = 'G:';
            app.MagField = uieditfield(app.SystemDataPanel, 'numeric');
            app.MagField.ValueDisplayFormat = '%.3f';
            app.MagField.Editable = 'off';
            app.MagField.Tooltip = {'Angular magnification of central ray from first active object point. Uses first wavelength (red in the case of RGB).'};
            app.ExitangledevLabel = uilabel(app.SystemDataPanel);
            app.ExitangledevLabel.Text = 'Exit angle dev:';
            app.AngleDeviationField = uieditfield(app.SystemDataPanel, 'numeric');
            app.AngleDeviationField.ValueDisplayFormat = '%.5f';
            app.AngleDeviationField.Editable = 'off';
            app.AngleDeviationField.Tooltip = {'Maximal angular deviation of rays from their average direction upon exit, computed for the first active object point and the first wavelength. Ideally zero (0) for parallel rays.'};
            app.degLabel = uilabel(app.SystemDataPanel);
            app.degLabel.Tooltip = {'Maximal angular deviation of rays from their average direction upon exit, computed for the first active object point and the first wavelength. Ideally zero (0) for parallel rays.'};
            app.degLabel.Text = '[deg]';
            app.AngleDevGauge = uigauge(app.SystemDataPanel, 'linear');
            app.AngleDevGauge.Limits = [0 0.05];
            app.AngleDevGauge.Tooltip = {'Maximal angular deviation of rays from their average direction upon exit, computed for the first active object point and the first wavelength. Ideally zero (0) for parallel rays.'};
            app.OptimizeCheckBox = uicheckbox(app.SystemDataPanel);
            app.OptimizeCheckBox.Tooltip = {'Optimize position (for lowest exit angle dev.) upon trace. Lenses need to be placed in correct order for this to work.'};
            app.OptimizeCheckBox.Text = 'Optimize';
            app.TracetypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TracetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TracetypeButtonGroupSelectionChanged, true);
            app.TracetypeButtonGroup.Title = 'Trace type';
            app.NormalButton = uiradiobutton(app.TracetypeButtonGroup);
            app.NormalButton.Tooltip = {'Trace rays from the object point.'};
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.ReverseButton = uiradiobutton(app.TracetypeButtonGroup);
            app.ReverseButton.Tooltip = {'Trace rays from the image (placed at infinity).'};
            app.ReverseButton.Text = 'Reverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LensSystem3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end